classdef I_AppCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PVGeneratorleistunginkWpSliderLabel  matlab.ui.control.Label
        PVGeneratorleistunginkWpSlider  matlab.ui.control.Slider
        AnzahlderEinwohnerSpinnerLabel  matlab.ui.control.Label
        AnzahlderEinwohnerSpinner       matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GesamtversorgungskonzepteinesEinfamilienhausesLabel  matlab.ui.control.Label
        BatteriespeicherkapazittinkWhSliderLabel  matlab.ui.control.Label
        BatteriespeicherkapazittinkWhSlider  matlab.ui.control.Slider
        SaisonalerWrmespeicherinmSliderLabel  matlab.ui.control.Label
        SaisonalerWrmespeicherinmSlider  matlab.ui.control.Slider
        AutarkiegradStromversorgungEditFieldLabel  matlab.ui.control.Label
        AutarkiegradStromversorgungEditField  matlab.ui.control.EditField
        AutarkiegradWrmeversorgungEditFieldLabel  matlab.ui.control.Label
        AutarkiegradWrmeversorgungEditField  matlab.ui.control.EditField
        AutarkiegradGesamtversorgungEditFieldLabel  matlab.ui.control.Label
        AutarkiegradGesamtversorgungEditField  matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
       ERG % Ergebnismatrix
       PVgen % PV-Generatorleistung
       PVgen_delta % Schrittweite PV-Generatorleistung
       E_BAT % Batteriekapazität in kWh
       E_BAT_delta % Schrittweite Batteriekapazität
       E_TCS % Speicherkapazität Thermochemischer Speicher (TCS)
       E_TCS_delta % Schrittweite Speicherkapazität Thermochemischer Speicher(TCS)
       n % Anzahl der Einwohner 
       znr % Zeilennummer der gewählten Variation
       c % Farben
       p % Pie Chart
    end
    
    methods (Access = private)
        
        function results = func(app)

            for i = 1:1:3920;
    
                if app.ERG.ERG(i,2)==app.PVgen  && app.ERG.ERG(i,3)==app.E_BAT && app.ERG.ERG(i,4)==app.E_TCS && app.ERG.ERG(i,5) == app.n 
                    app.znr = i;      
                end
            end
        
%           app.EditField.Text = strrep(['Autarkiegrad: ',num2str(app.ERG(app.znr,11),'%0.1f'),' %'], '.', ',');
            
            app.AutarkiegradStromversorgungEditField.Value = num2str(round(app.ERG.ERG(app.znr,6)*100,1));
            app.AutarkiegradWrmeversorgungEditField.Value = num2str(round(app.ERG.ERG(app.znr,10)*100,1));
            app.AutarkiegradGesamtversorgungEditField.Value = num2str(round(app.ERG.ERG(app.znr,15)*100,1));
            
            
            % 1. Kreisdiagramm: Stromversorgung
            % Zusammensetzung: PV-Direktversorgung (7), Batterieversorgung (8) und Netzbezug (9)
            app.UIAxes.Title.String='Stromversorgung'; 
            app.p=pie(app.UIAxes,[app.ERG.ERG(app.znr,7),app.ERG.ERG(app.znr,8),app.ERG.ERG(app.znr,9)]);
            colormap(app.UIAxes,[app.c.gelb; app.c.gruen; app.c.dgrau]);
            legend(app.UIAxes,{'PV-Direktversorgung','Batterieversorgung','Netzbezug'},'Location','southoutside','Box','off','Color','none','FontSize',10)
            app.UIAxes.XLim=[-1.2 1.2];
            app.UIAxes.YLim=app.UIAxes.XLim;


            %2. Kreisdiagramm: Wärmeversorgung
            %Zusammensetzung: Wärme aus TCS (11), Wärme aus PV & Batterie
            %(12) und Wärme aus Netz(13)
            app.UIAxes2.Title.String='Wärmeversorgung'; 
            app.p=pie(app.UIAxes2,[app.ERG.ERG(app.znr,11),app.ERG.ERG(app.znr,12),app.ERG.ERG(app.znr,13)]);
            colormap(app.UIAxes2,[app.c.dgruen; app.c.gruen;app.c.dgrau]);
            legend(app.UIAxes2,{'Wärme aus TCS','Wärme aus PV mit Heizstab','Wärme aus Netz'},'Location','southoutside','Box','off','Color','none','FontSize',10)
            app.UIAxes2.XLim=[-1.2 1.2];
            app.UIAxes2.YLim=app.UIAxes2.XLim;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ERG = load(['I_ERG.mat']);
            app.PVgen = 4;
            app.PVgen_delta = 2;
            app.E_BAT = 2;
            app.E_BAT_delta = 2;
            app.E_TCS = 400;
            app.E_TCS_delta = 400;
            app.n = 1;   
            
            % Farben (RGB-Code) definieren
            app.c.gruen = [118, 185, 0]./255;
            app.c.dgruen = [51, 102, 0]./255;
            app.c.grau = [195, 195, 195]./255;
            app.c.dgrau = [63, 63, 63]./255;
            app.c.gelb = [255, 204, 0]./255;

            func (app)
        end

        % Value changed function: PVGeneratorleistunginkWpSlider
        function PVGeneratorleistunginkWpSliderValueChanged(app, event)
            value = app.PVGeneratorleistunginkWpSlider.Value;
            app.PVgen = round(value/app.PVgen_delta)*app.PVgen_delta;
            func (app)
        end

        % Value changed function: 
        % BatteriespeicherkapazittinkWhSlider
        function BatteriespeicherkapazittinkWhSliderValueChanged(app, event)
            value = app.BatteriespeicherkapazittinkWhSlider.Value;
            app.E_BAT = round(value/app.E_BAT_delta)*app.E_BAT_delta;
            func (app)
        end

        % Value changed function: SaisonalerWrmespeicherinmSlider
        function SaisonalerWrmespeicherinmSliderValueChanged(app, event)
            value = app.SaisonalerWrmespeicherinmSlider.Value;
            app.E_TCS = round(value)
            app.E_TCS = app.E_TCS * 400
            func (app)
        end

        % Value changed function: AnzahlderEinwohnerSpinner
        function AnzahlderEinwohnerSpinnerValueChanged(app, event)
            value = app.AnzahlderEinwohnerSpinner.Value;
            app.n = value
            func (app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PVGeneratorleistunginkWpSliderLabel = uilabel(app.UIFigure);
            app.PVGeneratorleistunginkWpSliderLabel.Text = 'PV - Generatorleistung in kWp';
            app.PVGeneratorleistunginkWpSlider = uislider(app.UIFigure);
            app.PVGeneratorleistunginkWpSlider.Limits = [4 30];
            app.PVGeneratorleistunginkWpSlider.MajorTicks = [4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.PVGeneratorleistunginkWpSlider.ValueChangedFcn = createCallbackFcn(app, @PVGeneratorleistunginkWpSliderValueChanged, true);
            app.PVGeneratorleistunginkWpSlider.MinorTicks = [];
            app.PVGeneratorleistunginkWpSlider.Value = 4;
            app.AnzahlderEinwohnerSpinnerLabel = uilabel(app.UIFigure);
            app.AnzahlderEinwohnerSpinnerLabel.Text = 'Anzahl der Einwohner';
            app.AnzahlderEinwohnerSpinner = uispinner(app.UIFigure);
            app.AnzahlderEinwohnerSpinner.Limits = [1 4];
            app.AnzahlderEinwohnerSpinner.ValueChangedFcn = createCallbackFcn(app, @AnzahlderEinwohnerSpinnerValueChanged, true);
            app.AnzahlderEinwohnerSpinner.Editable = 'off';
            app.AnzahlderEinwohnerSpinner.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.GesamtversorgungskonzepteinesEinfamilienhausesLabel = uilabel(app.UIFigure);
            app.GesamtversorgungskonzepteinesEinfamilienhausesLabel.Text = 'Gesamtversorgungskonzept eines Einfamilienhauses ';
            app.BatteriespeicherkapazittinkWhSliderLabel = uilabel(app.UIFigure);
            app.BatteriespeicherkapazittinkWhSliderLabel.Text = 'Batteriespeicherkapazität in kWh';
            app.BatteriespeicherkapazittinkWhSlider = uislider(app.UIFigure);
            app.BatteriespeicherkapazittinkWhSlider.Limits = [2 20];
            app.BatteriespeicherkapazittinkWhSlider.ValueChangedFcn = createCallbackFcn(app, @BatteriespeicherkapazittinkWhSliderValueChanged, true);
            app.BatteriespeicherkapazittinkWhSlider.MinorTicks = [2 6 10 14 18];
            app.BatteriespeicherkapazittinkWhSlider.Value = 2;
            app.SaisonalerWrmespeicherinmSliderLabel = uilabel(app.UIFigure);
            app.SaisonalerWrmespeicherinmSliderLabel.Text = 'Saisonaler Wärmespeicher in m³';
            app.SaisonalerWrmespeicherinmSlider = uislider(app.UIFigure);
            app.SaisonalerWrmespeicherinmSlider.Limits = [1 7];
            app.SaisonalerWrmespeicherinmSlider.MajorTicks = [1 2 3 4 5 6 7];
            app.SaisonalerWrmespeicherinmSlider.ValueChangedFcn = createCallbackFcn(app, @SaisonalerWrmespeicherinmSliderValueChanged, true);
            app.SaisonalerWrmespeicherinmSlider.MinorTicks = [];
            app.SaisonalerWrmespeicherinmSlider.Value = 7;
            app.AutarkiegradStromversorgungEditFieldLabel = uilabel(app.UIFigure);
            app.AutarkiegradStromversorgungEditFieldLabel.Text = {'Autarkiegrad'; 'Stromversorgung'};
            app.AutarkiegradStromversorgungEditField = uieditfield(app.UIFigure, 'text');
            app.AutarkiegradStromversorgungEditField.Editable = 'off';
            app.AutarkiegradWrmeversorgungEditFieldLabel = uilabel(app.UIFigure);
            app.AutarkiegradWrmeversorgungEditFieldLabel.Text = {'Autarkiegrad'; 'Wärmeversorgung'};
            app.AutarkiegradWrmeversorgungEditField = uieditfield(app.UIFigure, 'text');
            app.AutarkiegradWrmeversorgungEditField.Editable = 'off';
            app.AutarkiegradGesamtversorgungEditFieldLabel = uilabel(app.UIFigure);
            app.AutarkiegradGesamtversorgungEditFieldLabel.Text = {'Autarkiegrad'; 'Gesamtversorgung'};
            app.AutarkiegradGesamtversorgungEditField = uieditfield(app.UIFigure, 'text');
            app.AutarkiegradGesamtversorgungEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = I_AppCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end